classdef ProyectoFinal < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        TabGroup                        matlab.ui.container.TabGroup
        SoluciondeEcuacionesNoLinealesTab  matlab.ui.container.Tab
        txtFuncionGX                    matlab.ui.control.EditField
        txtLimiteSuperior               matlab.ui.control.NumericEditField
        LimiteSuperior                  matlab.ui.control.Label
        txtLimiteInferior               matlab.ui.control.NumericEditField
        LimiteInferior                  matlab.ui.control.Label
        Raiz                            matlab.ui.control.Label
        labelRaiz                       matlab.ui.control.Label
        txtValorCriterio                matlab.ui.control.NumericEditField
        ValordeCriterioEditFieldLabel   matlab.ui.control.Label
        optCriterioParada               matlab.ui.control.DropDown
        CriteriodeParadaDropDownLabel   matlab.ui.control.Label
        FXLabel                         matlab.ui.control.Label
        txtFuncion                      matlab.ui.control.EditField
        btnGraficar                     matlab.ui.control.Button
        btnLimpiar                      matlab.ui.control.Button
        btnSolucionar                   matlab.ui.control.Button
        optMetodoNumerico               matlab.ui.control.DropDown
        MetodoDropDownLabel             matlab.ui.control.Label
        Grafica                         matlab.ui.control.UIAxes
        DerivadasTab                    matlab.ui.container.Tab
        errorDerivada                   matlab.ui.control.Label
        labelRaiz_3                     matlab.ui.control.Label
        btnGraficar_2                   matlab.ui.control.Button
        btnLimpiar_2                    matlab.ui.control.Button
        btnSolucionar_2                 matlab.ui.control.Button
        ResultadoDerivada               matlab.ui.control.Label
        labelRaiz_2                     matlab.ui.control.Label
        valorHderivadas                 matlab.ui.control.NumericEditField
        ValordehEditFieldLabel          matlab.ui.control.Label
        valorXderivadas                 matlab.ui.control.NumericEditField
        ValordexEditFieldLabel          matlab.ui.control.Label
        optNumeroDerivada               matlab.ui.control.DropDown
        NumerodeDerivadaLabel           matlab.ui.control.Label
        funcionDerivacion               matlab.ui.control.EditField
        FuncionfxEditFieldLabel         matlab.ui.control.Label
        GraficaDerivada                 matlab.ui.control.UIAxes
        IntegralesTab                   matlab.ui.container.Tab
        optMetodoIntegral               matlab.ui.control.DropDown
        NumerodeDerivadaLabel_2         matlab.ui.control.Label
        hIntegral                       matlab.ui.control.Label
        labelRaiz_8                     matlab.ui.control.Label
        ErrorIntegral                   matlab.ui.control.Label
        labelRaiz_7                     matlab.ui.control.Label
        ResultadoIntegral               matlab.ui.control.Label
        labelRaiz_6                     matlab.ui.control.Label
        nIntegral                       matlab.ui.control.NumericEditField
        nEditFieldLabel                 matlab.ui.control.Label
        btnLimpiarIntegral              matlab.ui.control.Button
        btnSolucionarIntegral           matlab.ui.control.Button
        btnGraficarFuncionIntegral      matlab.ui.control.Button
        dxLabel                         matlab.ui.control.Label
        FuncionIntegral                 matlab.ui.control.EditField
        aIntegral                       matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        bIntegral                       matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        GraficaIntegral                 matlab.ui.control.UIAxes
        InterpolacionesTab              matlab.ui.container.Tab
        resultadoInterpolacion          matlab.ui.control.Label
        labelRaiz_5                     matlab.ui.control.Label
        EvaluacionX                     matlab.ui.control.NumericEditField
        NumPuntosMAX5EditFieldLabel_2   matlab.ui.control.Label
        PolinomioAjustado               matlab.ui.control.Label
        labelRaiz_4                     matlab.ui.control.Label
        CantidadPuntosInterpolacion     matlab.ui.control.NumericEditField
        NumPuntosMIN2MAX5Label          matlab.ui.control.Label
        InterpolacionX4                 matlab.ui.control.NumericEditField
        InterpolacionX3                 matlab.ui.control.NumericEditField
        InterpolacionX2                 matlab.ui.control.NumericEditField
        InterpolacionX1                 matlab.ui.control.NumericEditField
        InterpolacionY4                 matlab.ui.control.NumericEditField
        InterpolacionY3                 matlab.ui.control.NumericEditField
        InterpolacionY2                 matlab.ui.control.NumericEditField
        InterpolacionY1                 matlab.ui.control.NumericEditField
        InterpolacionY0                 matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        InterpolacionX0                 matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        btnGraficarInterpolacion        matlab.ui.control.Button
        btnLimpiarInterpolacion         matlab.ui.control.Button
        btnSolucionarInterpolacion      matlab.ui.control.Button
        GraficaInterpolacion            matlab.ui.control.UIAxes
        EcuacionesDiferencialesTab      matlab.ui.container.Tab
        K3Label                         matlab.ui.control.Label
        K2Label                         matlab.ui.control.Label
        K1Label                         matlab.ui.control.Label
        Yi1Label                        matlab.ui.control.Label
        btnLimpiarEcuacionDiferenciakl  matlab.ui.control.StateButton
        btnGraficarEcuacionDiferencial  matlab.ui.control.StateButton
        btnSolucionarEcuacionDiferencial  matlab.ui.control.StateButton
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Intervalor2                     matlab.ui.control.NumericEditField
        Intervalo1                      matlab.ui.control.NumericEditField
        IntervaloEditFieldLabel         matlab.ui.control.Label
        K3                              matlab.ui.control.Label
        K2                              matlab.ui.control.Label
        K1                              matlab.ui.control.Label
        Yi1                             matlab.ui.control.Label
        yEcuacionesDiferenciales        matlab.ui.control.NumericEditField
        valordeyEditFieldLabel          matlab.ui.control.Label
        xEcuacionesDiferenciales        matlab.ui.control.NumericEditField
        valordexEditFieldLabel          matlab.ui.control.Label
        hEcuacionesDiferenciales        matlab.ui.control.NumericEditField
        hLabel                          matlab.ui.control.Label
        dydxEcuacionesDiferenciales     matlab.ui.control.EditField
        dydxEditFieldLabel              matlab.ui.control.Label
        GraficaEcuaciones               matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = func(comp)
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnSolucionar
        function solucionar(comp, event)
            %declaramos que x va a ser una variable numerica de sistema
            syms x;
            format long;
            %Obtenemos la funcion del campo de texto y de una vez la
            %convertimos a variable simbolica con la funcion str2sym
            funcion = str2sym(comp.txtFuncion.Value);
            
            %Obtenemos los limites inferiores y superiores
            limInferior= comp.txtLimiteInferior.Value;
            limSuperior = comp.txtLimiteSuperior.Value;

            % Obtenemos el metodo que se quiere usar
            metodo = comp.optMetodoNumerico.Value;

            %Verificamos que el limite inferior sea menor al superior
            if limInferior >= limSuperior 
                if strcmp(metodo, "Bisección")
                    comp.Raiz.Text = "El limite inferior debe ser menor al limite superior";
                    comp.optMetodoNumerico.Value = "Error";
                elseif strcmp(metodo, "Falsa Posición")
                    comp.Raiz.Text = "El limite inferior debe ser menor al limite superior";
                    comp.optMetodoNumerico.Value = "Error";
                end
            end

            % Obtenemos el criterio de parada, ya sea por iteraciones o por
            % error maximo
            if strcmp(comp.optCriterioParada.Value,"Cantidad Iteraciones") == 1
                numIteraciones = comp.txtValorCriterio.Value;
              
                % Se identifica que metodo se quiere usar
                if strcmp(metodo, "Bisección")
                    iteracion = 0;
                    %Obtenemos el calculo de cada raiz en cada iteracion
                    while(iteracion <= numIteraciones)                                  
                        xr = limInferior;
                        xra = xr;
                        xr = (limInferior+limSuperior)/2;
                        fxs = subs(funcion, x, limSuperior);
                        fxr = subs(funcion, x, xr);
                        fxa = subs(funcion, x, limInferior);
                        error = abs(xr-xra);
                        
                        arregloValores = [iteracion, limInferior, xr, limSuperior, fxs, fxr, fxa, 0, 0, error];

                        if((fxr*fxa)>0)
                            limInferior = xr;
                        end
                        if((fxr*fxa)<0)
                            limSuperior = xr;
                        end
                        
                        iteracion = iteracion+1;
                        if(iteracion == numIteraciones)
                            break;
                        end
 
                    end
                   
                    comp.Raiz.Text = num2str(xr);

                elseif strcmp(metodo, "Falsa Posición")
                    iteracion = 0;
                    while(iteracion <= numIteraciones)              
                        xra = limInferior;
                        fxs = subs(funcion, x, limSuperior);
                        fxa = subs(funcion, x, limInferior);
                        
                        xr = (limSuperior - ((fxs*(limSuperior-limInferior))/(fxs-fxa)));
                        fxr = subs(funcion, x, xr);

                        error = abs(xr-xra);

                        limInferior = xr;
                        iteracion = iteracion+1;

                        if(iteracion == numIteraciones)
                            break;
                        end
                    end

                    xr = eval(xr);
                    comp.Raiz.Text = num2str(xr);
                   
                elseif strcmp(metodo, "Punto Fijo")
                    iteracion = 0;
                    xr = limInferior;
                    gx = str2sym(comp.txtFuncionGX.Value);
                    
                    while (iteracion <= numIteraciones)
                        gxr = subs(gx, x, xr);
                        
                        xr = gxr;
                        iteracion = iteracion +1;
                        if(iteracion == numIteraciones)
                            break;
                        end
                    end
                    xr = eval(xr);
                    comp.Raiz.Text = num2str(xr);

                elseif strcmp(metodo, "Newton-Rapston")
                     iteracion = 0;
                     xr = limInferior;

                    while(iteracion <= numIteraciones)
                       
                        fxr = subs(funcion, x, xr);
                        derivadaFx = diff(funcion, x, 1);
                        derivadaFxr = double(subs(derivadaFx, xr));
                        xr = double( xr - (fxr/derivadaFxr));
                        iteracion = iteracion +1;
                        if(iteracion == numIteraciones)
                            break;
                        end
                    end
                    comp.Raiz.Text = num2str(xr);
                end


            end
        end

        % Button pushed function: btnLimpiar
        function limpiar(comp, event)
           %Limpiamos todos los campos de texto y las graficas
           comp.txtFuncion.Value = "";
           comp.txtValorCriterio.Value = 0;
           comp.txtLimiteInferior.Value = 0;
           comp.txtLimiteSuperior.Value = 0;
           comp.Raiz.Text = "";
           cla(comp.Grafica,'reset');
           
        end

        % Button pushed function: btnGraficar
        function graficar(comp, event)
            %declaramos que x va a ser una variable numerica de sistema
            syms x;
            %Obtenemos la funcion del campo de texto y de una vez la
            %convertimos a variable simbolica con la funcion str2sym
            funcion = str2sym(comp.txtFuncion.Value);

            %Obtenemos los limites ingresados en los campos numericos
            limInferior= comp.txtLimiteInferior.Value;
            limSuperior = comp.txtLimiteSuperior.Value;

            %aumentamos en 1 los limites para que la grafica se vea mejor
            lI = limInferior-1;
            lS = limSuperior+1;

            %Con la funcion fplot generamos la grafica de la funcion
            fplot(comp.Grafica,funcion,[lI lS]);
        end

        % Button pushed function: btnSolucionar_2
        function solucionarDerivada(comp, event)
            syms x;
            funcion = str2sym(comp.funcionDerivacion.Value);
            numeroDerivada = comp.optNumeroDerivada.Value;
            xi = comp.valorXderivadas.Value;
            h = comp.valorHderivadas.Value;

           xatras1 = xi - h;
           xatras2 = xatras1 - h;
           xatras3 = xatras2 - h;
           xdelante1 = xi + h;
           xdelante2 = xdelante1 + h;
           xdelante3 = xdelante2 + h;
           
           fxi = subs(funcion, x, xi);

           fxd1 = subs(funcion, x, xdelante1);
           fxd2 = subs(funcion, x, xdelante2);
           fxd3 = subs(funcion, x, xdelante3);
           
           fxa1 = subs(funcion, x, xatras1);
           fxa2 = subs(funcion, x, xatras2);
           fxa3 = subs(funcion, x, xatras3);

           derivada1Real = diff(funcion, x, 1);
             dv1Realxi = subs(derivada1Real, xi);
             derivada2Real = diff(funcion, x, 2);
             dv2Realxi = subs(derivada1Real, xi);
             derivada2Real = diff(funcion, x, 3);
             dv3Realxi = subs(derivada1Real, xi);

             % Se identifica que derivada se quiere calcular
                if strcmp(numeroDerivada, "Primera")

                   resultado = double((-fxd2 + 8*fxd1 - 8*fxa1 +fxa2)/(12*h));

                   error = double(abs((dv1Realxi - resultado )/dv1Realxi));


                elseif strcmp(numeroDerivada, "Segunda")
                   
                   resultado = double((-fxd2 + 16*fxd1 - 30*fxi + 16*fxa1 - fxa2)/(12*(h^2)));

                   error = double(abs((dv2Realxi - resultado)/dv2Realxi));

                elseif strcmp(numeroDerivada, "Tercera")
                   
                   resultado = double((-fxd2 - 4*fxd1 + 6*fxi - 4*fxa1 + fxa2)/(h^4));

                   error = double(abs((dv3Realxi - resultado)/dv3Realxi));

                end
             comp.ResultadoDerivada.Text = num2str(resultado);
             comp.errorDerivada.Text = num2str(error);
  
        end

        % Button pushed function: btnGraficar_2
        function graficarDerivada(comp, event)
             %declaramos que x va a ser una variable numerica de sistema
            syms x;
            %Obtenemos la funcion del campo de texto y de una vez la
            %convertimos a variable simbolica con la funcion str2sym
            funcion = str2sym(comp.funcionDerivacion.Value);

            xinicial = comp.valorXderivadas.Value - 1;

            %Con la funcion fplot generamos la grafica de la funcion
            fplot(comp.GraficaDerivada,funcion,[xinicial 10]);
        end

        % Button pushed function: btnLimpiar_2
        function limpiarDerivada(comp, event)
            %Limpiamos todos los campos de texto y las graficas
           comp.funcionDerivacion.Value = "";
           comp.valorXderivadas.Value = 0;
           comp.valorHderivadas.Value = 0;
           comp.ResultadoDerivada.Text = "";
           cla(comp.GraficaDerivada,'reset');
        end

        % Button down function: GraficaInterpolacion
        function limpiarInterpolacion(comp, event)
            %Limpiamos todos los campos de texto y las graficas
           comp.CantidadPuntosInterpolacion.Value = 0;
           comp.InterpolacionX0.Value = 0;
           comp.InterpolacionX1.Value = 0;
           comp.InterpolacionX2.Value = 0;
           comp.InterpolacionX3.Value = 0;
           comp.InterpolacionX4.Value = 0;
           comp.InterpolacionY0.Value = 0;
           comp.InterpolacionY1.Value = 0;
           comp.InterpolacionY2.Value = 0;
           comp.InterpolacionY3.Value = 0;
           comp.InterpolacionY4.Value = 0;
           comp.PolinomioAjustado.Text = "";
           cla(comp.GraficaInterpolacion,'reset');
        end

        % Button pushed function: btnGraficarInterpolacion
        function graficarInterpolacion(comp, event)
            if(comp.PolinomioAjustado.Text == "")
            else
                 %declaramos que x va a ser una variable numerica de sistema
                syms x;
                %Obtenemos la funcion del campo de texto y de una vez la
                %convertimos a variable simbolica con la funcion str2sym
                funcion = str2sym(comp.PolinomioAjustado.Text);
    
                xinicial = comp.InterpolacionX0.Value - 1;
    
                %Con la funcion fplot generamos la grafica de la funcion
                fplot(comp.GraficaInterpolacion,funcion,[xinicial 10]);
            end
        end

        % Button pushed function: btnSolucionarInterpolacion
        function solucionarInterpolacion(comp, event)
            syms x;
            nPuntos = comp.CantidadPuntosInterpolacion.Value;
            xEval = comp.EvaluacionX.Value;

            x0 = comp.InterpolacionX0.Value;
            x1 = comp.InterpolacionX1.Value;
            x2 = comp.InterpolacionX2.Value;
            x3 = comp.InterpolacionX3.Value;
            x4 = comp.InterpolacionX4.Value;

            y0 = comp.InterpolacionY0.Value;
            y1 = comp.InterpolacionY1.Value;
            y2 = comp.InterpolacionY2.Value;
            y3 = comp.InterpolacionY3.Value;
            y4 = comp.InterpolacionY4.Value;

            if(nPuntos == 2)
                fx0 = y0;
                fx1 = y1;

                % Primeras Diferencias
                fx0x1 = double((fx1 - fx0)/(x1 - x0));
                b0 = fx0;
                b1 = fx0x1;
                resultado = double(b0 + b1 * (xEval - x0));
                b0 = num2str(b0);
                b1 = num2str(b1);
                x0 = num2str(x0);
                
                strPolinomio = strcat(b0, "+ (", b1, ") * ( x - (", x0 , "))");
                
            elseif(nPuntos == 3)
                fx0 = y0;
                fx1 = y1;
                fx2 = y2;

                %Primeras Diferencias
                fx0x1 = double((fx1 - fx0)/(x1 - x0));
                fx1x2 = double((fx2 - fx1)/(x2 - x1));

                %Segundas Diferencias
                fx0x1x2 = double((fx1x2 - fx0x1)/(x2 - x0));

                b0 = fx0;
                b1 = fx0x1;
                b2 = fx0x1x2;

                resultado = double(b0 + b1 * (xEval - x0) + b2 * (xEval - x0) * (xEval - x1));

                b0 = num2str(b0);
                b1 = num2str(b1);
                b2 = num2str(b2);
                x0 = num2str(x0);
                x1 = num2str(x1);
                
                strPolinomio = strcat(b0, " + (", b1, ") * ( x - (", x0 , "))", " + ", b2, "* (x - (", x0 , ")) * ( x - (", x1 , "))");
            elseif(nPuntos == 4)
                fx0 = y0;
                fx1 = y1;
                fx2 = y2;
                fx3 = y3;

                %Primeras Diferencias
                fx0x1 = double((fx1 - fx0)/(x1 - x0));
                fx1x2 = double((fx2 - fx1)/(x2 - x1));
                fx2x3 = double((fx3 - fx2)/(x3 - x2));

                %Segundas Diferencias
                fx0x1x2 = double((fx1x2 - fx0x1)/(x2 - x0));
                fx1x2x3 = double((fx2x3 - fx1x2)/(x3 - x1));

                % Terceras Diferencias
                fx0x1x2x3 = double((fx1x2x3 - fx0x1x2)/(x3 - x0));

                b0 = fx0;
                b1 = fx0x1;
                b2 = fx0x1x2;
                b3 = fx0x1x2x3;

                resultado = double(b0 + b1 * (xEval - x0) + ...
                                        b2 * (xEval - x0) * (xEval - x1)) + ...
                                        b3 * (xEval - x0) * (xEval - x1) * (xEval - x2);

                b0 = num2str(b0);
                b1 = num2str(b1);
                b2 = num2str(b2);
                b3 = num2str(b3);
                x0 = num2str(x0);
                x1 = num2str(x1);
                x2 = num2str(x2);

                strPolinomio = strcat(b0, " + (", b1, ") * (x - (", x0 , "))", " + (" ...
                                    , b2, ") * (x - (", x0 , ")) * (x - (", x1 , ")) + (" ...
                                    , b3 , ") * (x - (", x0 , ")) * (x - (", x1, ")) * (x - (", x2,"))");
            elseif(nPuntos == 5)
                fx0 = y0;
                fx1 = y1;
                fx2 = y2;
                fx3 = y3;
                fx4 = y4;

                %Primeras Diferencias
                fx0x1 = double((fx1 - fx0)/(x1 - x0));
                fx1x2 = double((fx2 - fx1)/(x2 - x1));
                fx2x3 = double((fx3 - fx2)/(x3 - x2));
                fx3x4 = double((fx4 - fx3)/(x4 - x3));

                %Segundas Diferencias
                fx0x1x2 = double((fx1x2 - fx0x1)/(x2 - x0));
                fx1x2x3 = double((fx2x3 - fx1x2)/(x3 - x1));
                fx2x3x4 = double((fx3x4 - fx2x3)/(x4 - x2));

                % Terceras Diferencias
                fx0x1x2x3 = double((fx1x2x3 - fx0x1x2)/(x3 - x0));
                fx1x2x3x4 = double((fx2x3x4 - fx1x2x3)/(x4 - x1));

                % Cuartas Diferencias
                fx0x1x2x3x4 = double((fx1x2x3x4 - fx0x1x2x3)/(x4 - x0));

                b0 = fx0;
                b1 = fx0x1;
                b2 = fx0x1x2;
                b3 = fx0x1x2x3;
                b4 = fx0x1x2x3x4;

                resultado = double(b0 + b1 * (xEval - x0) + ...
                                        b2 * (xEval - x0) * (xEval - x1) + ...
                                        b3 * (xEval - x0) * (xEval - x1) * (xEval - x2) + ...
                                        b4 * (xEval - x0) * (xEval - x1) * (xEval - x2) * (xEval - x3));

                b0 = num2str(b0);
                b1 = num2str(b1);
                b2 = num2str(b2);
                b3 = num2str(b3);
                b4 = num2str(b4);
                x0 = num2str(x0);
                x1 = num2str(x1);
                x2 = num2str(x2);
                x3 = num2str(x3);

                strPolinomio = strcat(b0, " + (", b1, ") * (x - (", x0 , "))", " + (" ...
                                    , b2, ") * (x - (", x0 , ")) * (x - (", x1 , ")) + (" ...
                                    , b3 , ") * (x - (", x0 , ")) * (x - (", x1, ")) * (x - (", x2,")) + (" ...
                                    ,b4,") * (x - (",x0,")) *  (x - (",x1,")) * (x - (",x2,")) * (x - (",x3,"))");
            end
            comp.PolinomioAjustado.Text = strPolinomio;
            comp.resultadoInterpolacion.Text = num2str(resultado);
        end

        % Callback function: GraficaIntegral, btnGraficarFuncionIntegral
        function graficarIntegral(comp, event)
            %declaramos que x va a ser una variable numerica de sistema
            syms x;
            %Obtenemos la funcion del campo de texto y de una vez la
            %convertimos a variable simbolica con la funcion str2sym
            funcion = str2sym(comp.FuncionIntegral.Value);

            xInicial = comp.aIntegral.Value - 1;
            xFinal = comp.bIntegral.Value + 1;

            %Con la funcion fplot generamos la grafica de la funcion
            fplot(comp.GraficaDerivada,funcion,[xInicial xFinal]);
        end

        % Button pushed function: btnLimpiarIntegral
        function limpiarIntegral(comp, event)
            comp.hIntegral.Text = "";
            comp.ErrorIntegral.Text = "";
            comp.ResultadoIntegral.Text = "";
            comp.nIntegral.Value = 0;
            comp.FuncionIntegral.Value = "";
            comp.aIntegral.Value = 0;
            comp.bIntegral.Value = 0;
            cla(comp.GraficaIntegral,'reset');

        end

        % Button pushed function: btnSolucionarIntegral
        function solucionarIntegral(comp, event)
            syms x;
            funcion = str2sym(comp.FuncionIntegral.Value);
            a = comp.aIntegral.Value;
            b = comp.bIntegral.Value;
            n = comp.nIntegral.Value;
            metodo = comp.optMetodoIntegral.Value;

            if(metodo == "Trapecio Múltiple")
                h = (b - a)/n;
                i = 1;
                sum1 = double(subs(funcion, x, a) + subs(funcion, x, b));
                sumatoria = 0;
                
                while i < n
                    xi = a + i * h;
                    sumatoria = double(sumatoria + subs(funcion, x, xi));
                    i = i + 1;
                end
                resultado = double(((b-a)/(2*n))*(sum1 + 2*sumatoria)); 
            
            elseif(metodo == "Simpson 1/3")
                
                h = (b - a)/n;
                i = 1;
                sum2 = double(subs(funcion, x, a) + subs(funcion, x, b));
                sumatoriaPar = 0;
                sumatoriaImpar = 0;

                while i < n
                    xi = a + i * h;
                    if mod(i,2)==0
                        sumatoriaPar = double(sumatoriaPar + subs(funcion, x, xi));
                        i = i + 1;
                    else
                        sumatoriaImpar = double(sumatoriaImpar + subs(funcion, x, xi));
                        i = i + 1;
                    end
                end
                
                resultado = double((b-a)*(sum2+(4*sumatoriaImpar)+(2*sumatoriaPar))/(3*n));

            elseif(metodo == "Simpson 3/8")
                h = (b - a)/3;
                i = 1;
                f0 = double(subs(funcion,x,a));
                f1 = double(subs(funcion,x,b));
                sumatoria=0;

                while i<3
                    xi = a + i * h;
                    sumatoria = double(sumatoria + subs(funcion, x, xi));
                    i=i+1;
                end
                resultado = double((b-a)*((f0+(3*sumatoria)+f1)/8));

            end
           
            comp.ResultadoIntegral.Text = num2str(resultado);
            comp.hIntegral.Text = num2str(h);
            valorReal = (integral(matlabFunction(funcion), a, b));

            error = double(abs(((valorReal) - (resultado))/(valorReal))*100);
            comp.ErrorIntegral.Text = num2str(error);
        end

        % Value changed function: btnSolucionarEcuacionDiferencial
        function btnSolucionarEcuacionDiferencialValueChanged(comp, event)
            syms x y

            funcion = str2sym(comp.dydxEcuacionesDiferenciales.Value);
            h = comp.hEcuacionesDiferenciales.Value;
            a = comp.xEcuacionesDiferenciales.Value;
            b = comp.yEcuacionesDiferenciales.Value;
            inter1 = comp.Intervalo1.Value;
            inter2 = comp.Intervalor2.Value;
            
            while inter1 < inter2

                k1 = double(subs(funcion, {x,y},{a,b}));
                k2 = double(subs(funcion, {x,y},{a+((1/2)*h),b+(1/2*k1*h)}));
                k3 = double(subs(funcion, {x,y},{a+h,b-(k1*h)+(2*k2*h)}));
                yi = double(b+(h/6*((k1)+(4*k2)+(k3))));

                a=a+h;
                b=yi;
                inter1 = inter1+h;
                fprintf("2");

            end
            comp.Yi1.Text = num2str(yi);
            comp.K1.Text = num2str(k1);
            comp.K2.Text = num2str(k2);
            comp.K3.Text = num2str(k3);
            
        end

        % Value changed function: btnLimpiarEcuacionDiferenciakl
        function btnLimpiarEcuacionDiferenciaklValueChanged(comp, event)
            comp.dydxEcuacionesDiferenciales.Value = "";
            comp.hEcuacionesDiferenciales.Value = 0;
            comp.xEcuacionesDiferenciales.Value = 0;
            comp.yEcuacionesDiferenciales.Value = 0;
            comp.Intervalo1.Value = 0;
            comp.Yi1.Text = "";
            comp.K1.Text = "";
            comp.K2.Text = "";
            comp.K3.Text = "";
            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 770 469];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.TabGroup = uitabgroup(comp);
            comp.SoluciondeEcuacionesNoLinealesTab = uitab(comp.TabGroup);
            comp.SoluciondeEcuacionesNoLinealesTab.Title = 'Solucion de Ecuaciones No Lineales';
            comp.Grafica = uiaxes(comp.SoluciondeEcuacionesNoLinealesTab);
            title(comp.Grafica, 'Gráfica')
            xlabel(comp.Grafica, 'X')
            ylabel(comp.Grafica, 'Y')
            zlabel(comp.Grafica, 'Z')
            comp.Grafica.Tag = 'grafica';
            comp.MetodoDropDownLabel = uilabel(comp.SoluciondeEcuacionesNoLinealesTab);
            comp.MetodoDropDownLabel.Text = 'Metodo:';
            comp.optMetodoNumerico = uidropdown(comp.SoluciondeEcuacionesNoLinealesTab);
            comp.optMetodoNumerico.Items = {'Bisección', 'Falsa Posición', 'Punto Fijo', 'Newton-Rapston'};
            comp.optMetodoNumerico.Value = 'Bisección';
            comp.btnSolucionar = uibutton(comp.SoluciondeEcuacionesNoLinealesTab, 'push');
            comp.btnSolucionar.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @solucionar, true);
            comp.btnSolucionar.Tag = 'btnSolucionar';
            comp.btnSolucionar.Text = 'Solucionar';
            comp.btnLimpiar = uibutton(comp.SoluciondeEcuacionesNoLinealesTab, 'push');
            comp.btnLimpiar.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @limpiar, true);
            comp.btnLimpiar.Tag = 'btnLimpiar';
            comp.btnLimpiar.Text = 'Limpiar';
            comp.btnGraficar = uibutton(comp.SoluciondeEcuacionesNoLinealesTab, 'push');
            comp.btnGraficar.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @graficar, true);
            comp.btnGraficar.Tag = 'btnGraficar';
            comp.btnGraficar.Text = 'Graficar';
            comp.txtFuncion = uieditfield(comp.SoluciondeEcuacionesNoLinealesTab, 'text');
            comp.txtFuncion.Tag = 'txtEcuacion';
            comp.FXLabel = uilabel(comp.SoluciondeEcuacionesNoLinealesTab);
            comp.FXLabel.Text = {'Función F(x):'; 'g(x) para Punto Fijo'};
            comp.CriteriodeParadaDropDownLabel = uilabel(comp.SoluciondeEcuacionesNoLinealesTab);
            comp.CriteriodeParadaDropDownLabel.Text = 'Criterio de Parada:';
            comp.optCriterioParada = uidropdown(comp.SoluciondeEcuacionesNoLinealesTab);
            comp.optCriterioParada.Items = {'Cantidad Iteraciones', 'Error'};
            comp.optCriterioParada.Tag = 'slcCriterioParada';
            comp.optCriterioParada.Value = 'Cantidad Iteraciones';
            comp.ValordeCriterioEditFieldLabel = uilabel(comp.SoluciondeEcuacionesNoLinealesTab);
            comp.ValordeCriterioEditFieldLabel.Text = 'Valor de Criterio:';
            comp.txtValorCriterio = uieditfield(comp.SoluciondeEcuacionesNoLinealesTab, 'numeric');
            comp.txtValorCriterio.Tag = 'txtValorCriterio';
            comp.labelRaiz = uilabel(comp.SoluciondeEcuacionesNoLinealesTab);
            comp.labelRaiz.Text = 'Raiz (Xr):';
            comp.Raiz = uilabel(comp.SoluciondeEcuacionesNoLinealesTab);
            comp.Raiz.Text = '';
            comp.LimiteInferior = uilabel(comp.SoluciondeEcuacionesNoLinealesTab);
            comp.LimiteInferior.Text = 'Xi:';
            comp.txtLimiteInferior = uieditfield(comp.SoluciondeEcuacionesNoLinealesTab, 'numeric');
            comp.txtLimiteInferior.Tag = 'txtValorCriterio';
            comp.LimiteSuperior = uilabel(comp.SoluciondeEcuacionesNoLinealesTab);
            comp.LimiteSuperior.Text = 'Xs:';
            comp.txtLimiteSuperior = uieditfield(comp.SoluciondeEcuacionesNoLinealesTab, 'numeric');
            comp.txtLimiteSuperior.Tag = 'txtValorCriterio';
            comp.txtFuncionGX = uieditfield(comp.SoluciondeEcuacionesNoLinealesTab, 'text');
            comp.txtFuncionGX.Tag = 'txtEcuacion';
            comp.DerivadasTab = uitab(comp.TabGroup);
            comp.DerivadasTab.Title = 'Derivadas';
            comp.GraficaDerivada = uiaxes(comp.DerivadasTab);
            title(comp.GraficaDerivada, 'Gráfica')
            xlabel(comp.GraficaDerivada, 'X')
            ylabel(comp.GraficaDerivada, 'Y')
            zlabel(comp.GraficaDerivada, 'Z')
            comp.GraficaDerivada.Tag = 'grafica';
            comp.FuncionfxEditFieldLabel = uilabel(comp.DerivadasTab);
            comp.FuncionfxEditFieldLabel.Text = 'Funcion f(x)';
            comp.funcionDerivacion = uieditfield(comp.DerivadasTab, 'text');
            comp.NumerodeDerivadaLabel = uilabel(comp.DerivadasTab);
            comp.NumerodeDerivadaLabel.Text = 'Numero de Derivada:';
            comp.optNumeroDerivada = uidropdown(comp.DerivadasTab);
            comp.optNumeroDerivada.Items = {'Primera', 'Segunda', 'Tercera'};
            comp.optNumeroDerivada.Value = 'Primera';
            comp.ValordexEditFieldLabel = uilabel(comp.DerivadasTab);
            comp.ValordexEditFieldLabel.Text = 'Valor de x:';
            comp.valorXderivadas = uieditfield(comp.DerivadasTab, 'numeric');
            comp.ValordehEditFieldLabel = uilabel(comp.DerivadasTab);
            comp.ValordehEditFieldLabel.Text = 'Valor de h:';
            comp.valorHderivadas = uieditfield(comp.DerivadasTab, 'numeric');
            comp.labelRaiz_2 = uilabel(comp.DerivadasTab);
            comp.labelRaiz_2.Text = 'Resultado:';
            comp.ResultadoDerivada = uilabel(comp.DerivadasTab);
            comp.ResultadoDerivada.Text = '';
            comp.btnSolucionar_2 = uibutton(comp.DerivadasTab, 'push');
            comp.btnSolucionar_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @solucionarDerivada, true);
            comp.btnSolucionar_2.Tag = 'btnSolucionar';
            comp.btnSolucionar_2.Text = 'Solucionar';
            comp.btnLimpiar_2 = uibutton(comp.DerivadasTab, 'push');
            comp.btnLimpiar_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @limpiarDerivada, true);
            comp.btnLimpiar_2.Tag = 'btnLimpiar';
            comp.btnLimpiar_2.Text = 'Limpiar';
            comp.btnGraficar_2 = uibutton(comp.DerivadasTab, 'push');
            comp.btnGraficar_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @graficarDerivada, true);
            comp.btnGraficar_2.Tag = 'btnGraficar';
            comp.btnGraficar_2.Text = 'Graficar';
            comp.labelRaiz_3 = uilabel(comp.DerivadasTab);
            comp.labelRaiz_3.Text = 'Error:';
            comp.errorDerivada = uilabel(comp.DerivadasTab);
            comp.errorDerivada.Text = '';
            comp.IntegralesTab = uitab(comp.TabGroup);
            comp.IntegralesTab.Title = 'Integrales';
            comp.GraficaIntegral = uiaxes(comp.IntegralesTab);
            title(comp.GraficaIntegral, 'Gráfica')
            xlabel(comp.GraficaIntegral, 'X')
            ylabel(comp.GraficaIntegral, 'Y')
            zlabel(comp.GraficaIntegral, 'Z')
            comp.GraficaIntegral.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @graficarIntegral, true);
            comp.GraficaIntegral.Tag = 'grafica';
            comp.Image = uiimage(comp.IntegralesTab);
            comp.Image.ImageSource = fullfile(pathToMLAPP, 'WhatsApp Image 2022-05-30 at 5.31.02 PM.jpeg');
            comp.bEditFieldLabel = uilabel(comp.IntegralesTab);
            comp.bEditFieldLabel.Text = 'b =';
            comp.bIntegral = uieditfield(comp.IntegralesTab, 'numeric');
            comp.aEditFieldLabel = uilabel(comp.IntegralesTab);
            comp.aEditFieldLabel.Text = 'a =';
            comp.aIntegral = uieditfield(comp.IntegralesTab, 'numeric');
            comp.FuncionIntegral = uieditfield(comp.IntegralesTab, 'text');
            comp.dxLabel = uilabel(comp.IntegralesTab);
            comp.dxLabel.Text = 'dx';
            comp.btnGraficarFuncionIntegral = uibutton(comp.IntegralesTab, 'push');
            comp.btnGraficarFuncionIntegral.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @graficarIntegral, true);
            comp.btnGraficarFuncionIntegral.Tag = 'btnGraficar';
            comp.btnGraficarFuncionIntegral.Text = 'Graficar';
            comp.btnSolucionarIntegral = uibutton(comp.IntegralesTab, 'push');
            comp.btnSolucionarIntegral.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @solucionarIntegral, true);
            comp.btnSolucionarIntegral.Tag = 'btnSolucionar';
            comp.btnSolucionarIntegral.Text = 'Solucionar';
            comp.btnLimpiarIntegral = uibutton(comp.IntegralesTab, 'push');
            comp.btnLimpiarIntegral.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @limpiarIntegral, true);
            comp.btnLimpiarIntegral.Tag = 'btnLimpiar';
            comp.btnLimpiarIntegral.Text = 'Limpiar';
            comp.nEditFieldLabel = uilabel(comp.IntegralesTab);
            comp.nEditFieldLabel.Text = 'n = ';
            comp.nIntegral = uieditfield(comp.IntegralesTab, 'numeric');
            comp.labelRaiz_6 = uilabel(comp.IntegralesTab);
            comp.labelRaiz_6.Text = 'Resultado:';
            comp.ResultadoIntegral = uilabel(comp.IntegralesTab);
            comp.ResultadoIntegral.Text = '';
            comp.labelRaiz_7 = uilabel(comp.IntegralesTab);
            comp.labelRaiz_7.Text = 'Error:';
            comp.ErrorIntegral = uilabel(comp.IntegralesTab);
            comp.ErrorIntegral.Text = '';
            comp.labelRaiz_8 = uilabel(comp.IntegralesTab);
            comp.labelRaiz_8.Text = 'h = ';
            comp.hIntegral = uilabel(comp.IntegralesTab);
            comp.hIntegral.Text = '';
            comp.NumerodeDerivadaLabel_2 = uilabel(comp.IntegralesTab);
            comp.NumerodeDerivadaLabel_2.Text = 'Método:';
            comp.optMetodoIntegral = uidropdown(comp.IntegralesTab);
            comp.optMetodoIntegral.Items = {'Trapecio Múltiple', 'Simpson 1/3', 'Simpson 3/8'};
            comp.optMetodoIntegral.Value = 'Trapecio Múltiple';
            comp.InterpolacionesTab = uitab(comp.TabGroup);
            comp.InterpolacionesTab.Title = 'Interpolaciones';
            comp.GraficaInterpolacion = uiaxes(comp.InterpolacionesTab);
            title(comp.GraficaInterpolacion, 'Gráfica')
            xlabel(comp.GraficaInterpolacion, 'X')
            ylabel(comp.GraficaInterpolacion, 'Y')
            zlabel(comp.GraficaInterpolacion, 'Z')
            comp.GraficaInterpolacion.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @limpiarInterpolacion, true);
            comp.GraficaInterpolacion.Tag = 'grafica';
            comp.btnSolucionarInterpolacion = uibutton(comp.InterpolacionesTab, 'push');
            comp.btnSolucionarInterpolacion.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @solucionarInterpolacion, true);
            comp.btnSolucionarInterpolacion.Tag = 'btnSolucionar';
            comp.btnSolucionarInterpolacion.Text = 'Solucionar';
            comp.btnLimpiarInterpolacion = uibutton(comp.InterpolacionesTab, 'push');
            comp.btnLimpiarInterpolacion.Tag = 'btnLimpiar';
            comp.btnLimpiarInterpolacion.Text = 'Limpiar';
            comp.btnGraficarInterpolacion = uibutton(comp.InterpolacionesTab, 'push');
            comp.btnGraficarInterpolacion.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @graficarInterpolacion, true);
            comp.btnGraficarInterpolacion.Tag = 'btnGraficar';
            comp.btnGraficarInterpolacion.Text = 'Graficar';
            comp.XEditFieldLabel = uilabel(comp.InterpolacionesTab);
            comp.XEditFieldLabel.Text = 'X';
            comp.InterpolacionX0 = uieditfield(comp.InterpolacionesTab, 'numeric');
            comp.YEditFieldLabel = uilabel(comp.InterpolacionesTab);
            comp.YEditFieldLabel.Text = 'Y';
            comp.InterpolacionY0 = uieditfield(comp.InterpolacionesTab, 'numeric');
            comp.InterpolacionY1 = uieditfield(comp.InterpolacionesTab, 'numeric');
            comp.InterpolacionY2 = uieditfield(comp.InterpolacionesTab, 'numeric');
            comp.InterpolacionY3 = uieditfield(comp.InterpolacionesTab, 'numeric');
            comp.InterpolacionY4 = uieditfield(comp.InterpolacionesTab, 'numeric');
            comp.InterpolacionX1 = uieditfield(comp.InterpolacionesTab, 'numeric');
            comp.InterpolacionX2 = uieditfield(comp.InterpolacionesTab, 'numeric');
            comp.InterpolacionX3 = uieditfield(comp.InterpolacionesTab, 'numeric');
            comp.InterpolacionX4 = uieditfield(comp.InterpolacionesTab, 'numeric');
            comp.NumPuntosMIN2MAX5Label = uilabel(comp.InterpolacionesTab);
            comp.NumPuntosMIN2MAX5Label.Text = {'Num Puntos:'; 'MIN 2, MAX 5'};
            comp.CantidadPuntosInterpolacion = uieditfield(comp.InterpolacionesTab, 'numeric');
            comp.labelRaiz_4 = uilabel(comp.InterpolacionesTab);
            comp.labelRaiz_4.Text = 'Polinomio Ajustado:';
            comp.PolinomioAjustado = uilabel(comp.InterpolacionesTab);
            comp.PolinomioAjustado.Text = '';
            comp.NumPuntosMAX5EditFieldLabel_2 = uilabel(comp.InterpolacionesTab);
            comp.NumPuntosMAX5EditFieldLabel_2.Text = 'X a Evaluar:';
            comp.EvaluacionX = uieditfield(comp.InterpolacionesTab, 'numeric');
            comp.labelRaiz_5 = uilabel(comp.InterpolacionesTab);
            comp.labelRaiz_5.Text = 'Valor Obtenido:';
            comp.resultadoInterpolacion = uilabel(comp.InterpolacionesTab);
            comp.resultadoInterpolacion.Text = '';
            comp.EcuacionesDiferencialesTab = uitab(comp.TabGroup);
            comp.EcuacionesDiferencialesTab.Title = 'Ecuaciones Diferenciales';
            comp.GraficaEcuaciones = uiaxes(comp.EcuacionesDiferencialesTab);
            title(comp.GraficaEcuaciones, 'Gráfica')
            xlabel(comp.GraficaEcuaciones, 'X')
            ylabel(comp.GraficaEcuaciones, 'Y')
            zlabel(comp.GraficaEcuaciones, 'Z')
            comp.GraficaEcuaciones.Tag = 'grafica';
            comp.dydxEditFieldLabel = uilabel(comp.EcuacionesDiferencialesTab);
            comp.dydxEditFieldLabel.Text = 'dy/dx';
            comp.dydxEcuacionesDiferenciales = uieditfield(comp.EcuacionesDiferencialesTab, 'text');
            comp.hLabel = uilabel(comp.EcuacionesDiferencialesTab);
            comp.hLabel.Text = 'valor de h:';
            comp.hEcuacionesDiferenciales = uieditfield(comp.EcuacionesDiferencialesTab, 'numeric');
            comp.valordexEditFieldLabel = uilabel(comp.EcuacionesDiferencialesTab);
            comp.valordexEditFieldLabel.Text = 'valor de x:';
            comp.xEcuacionesDiferenciales = uieditfield(comp.EcuacionesDiferencialesTab, 'numeric');
            comp.valordeyEditFieldLabel = uilabel(comp.EcuacionesDiferencialesTab);
            comp.valordeyEditFieldLabel.Text = 'valor de y:';
            comp.yEcuacionesDiferenciales = uieditfield(comp.EcuacionesDiferencialesTab, 'numeric');
            comp.Yi1 = uilabel(comp.EcuacionesDiferencialesTab);
            comp.Yi1.Text = '';
            comp.K1 = uilabel(comp.EcuacionesDiferencialesTab);
            comp.K1.Text = '';
            comp.K2 = uilabel(comp.EcuacionesDiferencialesTab);
            comp.K2.Text = '';
            comp.K3 = uilabel(comp.EcuacionesDiferencialesTab);
            comp.K3.Text = '';
            comp.IntervaloEditFieldLabel = uilabel(comp.EcuacionesDiferencialesTab);
            comp.IntervaloEditFieldLabel.Text = 'Intervalo: ';
            comp.Intervalo1 = uieditfield(comp.EcuacionesDiferencialesTab, 'numeric');
            comp.Intervalor2 = uieditfield(comp.EcuacionesDiferencialesTab, 'numeric');
            comp.Label = uilabel(comp.EcuacionesDiferencialesTab);
            comp.Label.Text = '[';
            comp.Label_2 = uilabel(comp.EcuacionesDiferencialesTab);
            comp.Label_2.Text = ']';
            comp.Label_3 = uilabel(comp.EcuacionesDiferencialesTab);
            comp.Label_3.Text = ',';
            comp.btnSolucionarEcuacionDiferencial = uibutton(comp.EcuacionesDiferencialesTab, 'state');
            comp.btnSolucionarEcuacionDiferencial.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @btnSolucionarEcuacionDiferencialValueChanged, true);
            comp.btnSolucionarEcuacionDiferencial.Text = 'Solucionar';
            comp.btnGraficarEcuacionDiferencial = uibutton(comp.EcuacionesDiferencialesTab, 'state');
            comp.btnGraficarEcuacionDiferencial.Text = 'Graficar';
            comp.btnLimpiarEcuacionDiferenciakl = uibutton(comp.EcuacionesDiferencialesTab, 'state');
            comp.btnLimpiarEcuacionDiferenciakl.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @btnLimpiarEcuacionDiferenciaklValueChanged, true);
            comp.btnLimpiarEcuacionDiferenciakl.Text = 'Limpiar';
            comp.Yi1Label = uilabel(comp.EcuacionesDiferencialesTab);
            comp.Yi1Label.Text = 'Yi+1=';
            comp.K1Label = uilabel(comp.EcuacionesDiferencialesTab);
            comp.K1Label.Text = 'K1=';
            comp.K2Label = uilabel(comp.EcuacionesDiferencialesTab);
            comp.K2Label.Text = 'K2=';
            comp.K3Label = uilabel(comp.EcuacionesDiferencialesTab);
            comp.K3Label.Text = 'K3=';
        end
    end
end